classdef appMaquinaEDO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Menu               matlab.ui.container.Menu
        SairMenu           matlab.ui.container.Menu
        RelatorioMenu      matlab.ui.container.Menu
        SobreMenu          matlab.ui.container.Menu
        AutoresMenu        matlab.ui.container.Menu
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        bAtualizar         matlab.ui.control.Button
        bgMetodos          matlab.ui.container.ButtonGroup
        rbTodos            matlab.ui.control.RadioButton
        rbPontoMedio       matlab.ui.control.RadioButton
        rbODE45            matlab.ui.control.RadioButton
        rbRK4              matlab.ui.control.RadioButton
        rbRK2              matlab.ui.control.RadioButton
        rbEulerM           matlab.ui.control.RadioButton
        rbEuler            matlab.ui.control.RadioButton
        PainelPanel        matlab.ui.container.Panel
        Y0Label            matlab.ui.control.Label
        eY0                matlab.ui.control.NumericEditField
        NLabel             matlab.ui.control.Label
        eN                 matlab.ui.control.NumericEditField
        bLabel             matlab.ui.control.Label
        eB                 matlab.ui.control.NumericEditField
        eA                 matlab.ui.control.NumericEditField
        aLabel             matlab.ui.control.Label
        eF                 matlab.ui.control.EditField
        ftyEditFieldLabel  matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        UITable            matlab.ui.control.Table
        UIAxes             matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app, [])
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
        syms y(t)
        strF = app.eF.Value;
        f = @(t,y) eval(vectorize(strF));
        a = app.eA.Value;
        b = app.eB.Value;        
        n = app.eN.Value;
        y0 = app.eY0.Value;
        
        escolhabg = app.bgMetodos.SelectedObject;
        escolhaM = find([app.rbEuler, app.rbEulerM, app.rbRK2,...
                         app.rbRK4, app.rbODE45, app.rbPontoMedio,...
                         app.rbTodos]==escolhabg);
        erroFuncao = MException("MATLAB:bAtualizar:BadF",...
                                "Introduza uma função em t e y");
        erroB = MException("MATLAB:bAtualizar:BadB",...
                           "Introduza um real maior que a");


        % outras 
        
        
        try
            
            try
                z = f(t,y);
                app.eF.BackgroundColor = "w";  
            catch
                app.eF.BackgroundColor = [1,0,0]; % [r,g,b]
                throw(erroFuncao)          
            end
            
            if ~(isreal(b) && b>a)
                app.eB.BackgroundColor = [1,0,0]; % [r,g,b]
                throw(erroB)   
            else
                 app.eB.BackgroundColor = [1,1,1]; % [r,g,b]               
            end
            
            
            yEuler = NEuler(f,a,b,n,y0);
            yRK2 = NRK2(f,a,b,n,y0);
            yEulerMelhorado = NEulerMelhorado(f,a,b,n,y0);
            yPontoMedio = N_PM(f,a,b,n,y0);
            yRK4 = NRK4(f,a,b,n,y0);
            yODE45=N_ODE45(f,a,b,n,y0);
            
            
            sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
            
            g =@(t) eval(vectorize(char(sExata)));
            
            h = (b-a)/n;
            t = a:h:b;
            yExata = g(t);
            erroEuler = abs(yExata-yEuler);
            erroEulerMelhorado=abs(yExata-yEulerMelhorado);
            erroRK2 = abs(yExata-yRK2);
            erroRK4=abs(yExata-yRK4);
            erroODE45=abs(yExata-yODE45);
            erroPontoMedio=abs(yExata-yPontoMedio);
            plot(app.UIAxes,t,yExata,"-ro")
            hold(app.UIAxes,"on")            

            switch escolhaM
                case 1
                    plot(app.UIAxes,t,yEuler,"-g*")   
                    legenda = {'Exata','Euler'};
                    tabela = [t.',yExata.',yEuler.',erroEuler.']; 
                    nomeColunas = {'t','Exata','Euler','erroEuler'};
                case 2
                    plot(app.UIAxes,t,yEulerMelhorado,"-m")
                    legenda={'Exata','EulerMelhorado'};
                    tabela = [t.',yExata.',yEulerMelhorado.',erroEulerMelhorado.'];
                    nomeColunas={'t','Exata','EulerMelhorado','erroEulerMelhorado'};
                case 3
                    plot(app.UIAxes,t,yRK2,"-bo")   
                    legenda = {'Exata','RK2'};
                    tabela = [t.',yExata.',yRK2.',erroRK2.']; 
                    nomeColunas = {'t','Exata','RK2','erroRK2'};                    
                case 4
                    plot(app.UIAxes,t,yRK4,"-y")
                    legenda={'Exata','RK4'};
                    tabela = [t.',yExata.',yRK4.',erroRK4.']; 
                    nomeColunas = {'t','Exata','RK4','erroRK4'};                    
                case 5
                    plot(app.UIAxes,t,yODE45,"-k")   
                    legenda = {'Exata','ODE45'};
                    tabela = [t.',yExata.',yODE45.',erroODE45.']; 
                    nomeColunas = {'t','Exata','ODE45','erroODE45'}; 
                case 6
                    plot(app.UIAxes,t,yPontoMedio,"-yd")   
                    legenda = {'Exata','PontoMedio'};
                    tabela = [t.',yExata.',yPontoMedio.',erroPontoMedio.']; 
                    nomeColunas = {'t','Exata','PontoMedio','erroPontoMedio'};                      
                case 7
                   plot(app.UIAxes,t,yEuler,"-g*")   
                   plot(app.UIAxes,t,yEulerMelhorado,"-m") 
                   plot(app.UIAxes,t,yRK2,"-bo") 
                   plot(app.UIAxes,t,yRK4,"-y") 
                   plot(app.UIAxes,t,yODE45,"-k")
                   plot(app.UIAxes,t,yPontoMedio,"-yd")
                   legenda = {'Exata','Euler','EulerMelhorado','RK2','RK4','ODE45','PontoMedio'};                  
                   tabela = [t.',yExata.',yEuler.',yEulerMelhorado.',yRK2.',yRK4.',yODE45.',yPontoMedio.',...
                             erroEuler.',erroEulerMelhorado.',erroRK2.', erroRK4.', erroODE45.', erroPontoMedio.']; 
                   nomeColunas = {'t','Exata','Euler','EulerMelhorado','RK2','RK4','ODE45','PontoMedio',...
                                  'erroEuler','erroEulerMelhorado','erroRK2','erroRK4','erroODE45','erroPontoMedio'};  
            end
            
            legend(app.UIAxes, legenda) 
            hold(app.UIAxes,"off")
            grid(app.UIAxes,"on")
            
            T = array2table(tabela);
            app.UITable.Data = T; 
            app.UITable.ColumnName = nomeColunas;
                
        catch ME
            errordlg(ME.message,"ERRO","modal")               
        end


        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            sobre
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open("RelatórioFranciscoRuivo_DanielRodrigues.pdf")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {354, 354};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SairMenu = uimenu(app.Menu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.RelatorioMenu = uimenu(app.Menu);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatorio';
            app.SobreMenu = uimenu(app.UIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AutoresMenu = uimenu(app.SobreMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.PainelPanel = uipanel(app.LeftPanel);
            app.PainelPanel.Title = 'Painel';
            app.ftyEditFieldLabel = uilabel(app.PainelPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.PainelPanel, 'text');
            app.eF.Value = '-2*t*y';
            app.aLabel = uilabel(app.PainelPanel);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.PainelPanel, 'numeric');
            app.eB = uieditfield(app.PainelPanel, 'numeric');
            app.eB.Value = 1.5;
            app.bLabel = uilabel(app.PainelPanel);
            app.bLabel.Text = 'b';
            app.eN = uieditfield(app.PainelPanel, 'numeric');
            app.eN.Value = 10;
            app.NLabel = uilabel(app.PainelPanel);
            app.NLabel.Text = 'N';
            app.eY0 = uieditfield(app.PainelPanel, 'numeric');
            app.eY0.Value = 2;
            app.Y0Label = uilabel(app.PainelPanel);
            app.Y0Label.Text = 'Y0';
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbODE45 = uiradiobutton(app.bgMetodos);
            app.rbODE45.Text = 'ODE45';
            app.rbPontoMedio = uiradiobutton(app.bgMetodos);
            app.rbPontoMedio.Text = 'PontoMedio';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Grafico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaEDO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end