classdef appMaquinaSED < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        SairMenu                       matlab.ui.container.Menu
        DocumentosMenu                 matlab.ui.container.Menu
        AutoresMenu                    matlab.ui.container.Menu
        RelatorioMenu                  matlab.ui.container.Menu
        ProblemasPrDefenidosMenu       matlab.ui.container.Menu
        MolaMassasemAmortecimentoMenu  matlab.ui.container.Menu
        MolaMassacomAmortecimentoMenu  matlab.ui.container.Menu
        PnduloMenu                     matlab.ui.container.Menu
        ModelosVibratoriosmecanicosMenu  matlab.ui.container.Menu
        CircuitosEletricosMenu         matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        bLimparDados                   matlab.ui.control.Button
        bAtualizar                     matlab.ui.control.Button
        bgMetodos                      matlab.ui.container.ButtonGroup
        rbTodos                        matlab.ui.control.RadioButton
        rbRK4                          matlab.ui.control.RadioButton
        rbRK2                          matlab.ui.control.RadioButton
        rbEulerM                       matlab.ui.control.RadioButton
        rbEuler                        matlab.ui.control.RadioButton
        SistemasdeEquaesDiferenciaisPanel  matlab.ui.container.Panel
        ddProblemas                    matlab.ui.control.DropDown
        ProblemasPLabel                matlab.ui.control.Label
        NIteraesLabel                  matlab.ui.control.Label
        CondIniciaisLabel              matlab.ui.control.Label
        IntervaloLabel                 matlab.ui.control.Label
        v0Label                        matlab.ui.control.Label
        eV0                            matlab.ui.control.NumericEditField
        eG                             matlab.ui.control.EditField
        gtuvLabel                      matlab.ui.control.Label
        u0Label                        matlab.ui.control.Label
        eU0                            matlab.ui.control.NumericEditField
        nLabel                         matlab.ui.control.Label
        eN                             matlab.ui.control.NumericEditField
        bLabel                         matlab.ui.control.Label
        eB                             matlab.ui.control.NumericEditField
        eA                             matlab.ui.control.NumericEditField
        aLabel                         matlab.ui.control.Label
        eF                             matlab.ui.control.EditField
        ftuvLabel                      matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        function y = NODE23(~,f,a,b,n,y0)
            h = (b-a)/n;
            t = a:h:b;
            [~,y] = ode23(f,t,y0);
            y = y.';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app, [])
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
        syms u(t) v(t)
        strF = app.eF.Value;
        strG = app.eG.Value;
        f = @(t,u,v) eval(vectorize(strF));
        g = @(t,u,v) eval(vectorize(strG));        
        a = app.eA.Value;
        b = app.eB.Value;        
        n = app.eN.Value;
        u0 = app.eU0.Value;
        v0 = app.eV0.Value;
        
        escolhabg = app.bgMetodos.SelectedObject;
        escolhaM = find([app.rbEuler, app.rbEulerM,...
                         app.rbRK2,app.rbRK4,...
                         app.rbTodos]==escolhabg);
        erroFuncaoG = MException("MATLAB:bAtualizar:BadF",...
                                "Introduza uma função em t, u e v");
        erroB = MException("MATLAB:bAtualizar:BadF",...
                            "Introduza um número real maior que a");
        % outras 
        
        
        try
            
            try
                z = g(t,u,v);
                app.eG.BackgroundColor = "w";  
            catch
                app.eG.BackgroundColor = [1,0,0]; % [r,g,b]
                throw(erroFuncaoG)          
            end
            
            if ~(isreal(b) && b>a)
                    app.eB.BackgroundColor = [1,0,0]; % [r,g,b]
                    throw(erroB)  
            else 
                app.eB.BackgroundColor = [1,1,1]; % [r,g,b]
            end
            
            eqns = [diff(u,t)==v,diff(v,t)==-16*u];
            ci = [u(0)==u0,v(0)==v0];
            [sExataU,~] = dsolve(eqns,ci);
            
            h =@(t) eval(vectorize(char(sExataU)));
            
            
            [t,uEuler,~] = NEulerSED(f,g,a,b,n,u0,v0);
            [~,uRK2,~] = NRK2SED(f,g,a,b,n,u0,v0);
            [~,uEulerMelhorado,~] = NEulerMelhoradoSED(f,g,a,b,n,u0,v0);
            [~,uRK4,~] = NRK4SED(f,g,a,b,n,u0,v0);
            
            uExata = h(t);
            
            erroRK4 = abs(uExata-uRK4);
            erroEulerMelhorado = abs(uExata-uEulerMelhorado);
            erroEuler = abs(uExata-uEuler);
            erroRK2 = abs(uExata-uRK2); 
             
            
            plot(app.UIAxes,t,uExata,"-ro")
            hold(app.UIAxes,"on")            

            switch escolhaM
                case 1
                    plot(app.UIAxes,t,uEuler,"-g*")   
                    legenda = {'Exata','Euler'};
                    tabela = [t.',uExata.',uEuler.',erroEuler.']; 
                    nomeColunas = {'t','Exata','Euler','erroEuler'};
                case 2
                    plot(app.UIAxes,t,uEulerMelhorado,"-y")   
                    legenda = {'Exata','EulerMelhorado'};
                    tabela = [t.',uExata.',uEulerMelhorado.',erroEulerMelhorado.']; 
                    nomeColunas = {'t','Exata','EulerMelhorado','erroEulerMelhorado'};
                case 3
                    plot(app.UIAxes,t,uRK2,"-bo")   
                    legenda = {'Exata','RK2'};
                    tabela = [t.',uExata.',uRK2.',erroRK2.']; 
                    nomeColunas = {'t','Exata','RK2','erroRK2'};                    
                case 4
                    plot(app.UIAxes,t,uRK4,"-m")   
                    legenda = {'Exata','RK4'};
                    tabela = [t.',uExata.',uRK4.',erroRK4.']; 
                    nomeColunas = {'t','Exata','RK4','erroRK4'};                   
                case 5
                   plot(app.UIAxes,t,uEuler,"-g*") 
                   plot(app.UIAxes,t,uEulerMelhorado,"-y")  
                   plot(app.UIAxes,t,uRK2,"-bo")
                   plot(app.UIAxes,t,uRK4,"-m")  
                   legenda = {'Exata','Euler','EulerMelhorado','RK2','RK4'};                  
                   tabela = [t.',uExata.',uEuler.',uEulerMelhorado.',uRK2.',uRK4.',...
                             erroEuler.',erroEulerMelhorado.',erroRK2.',erroRK4.']; 
                   nomeColunas = {'t','Exata','Euler','EulerMelhorado','RK2','RK4',...
                                  'erroEuler','erroEulerMelhorado','erroRK2','erroRK4'}; 

            end
            
            legend(app.UIAxes, legenda) 
            hold(app.UIAxes,"off")
            grid(app.UIAxes,"on")
            
            T = array2table(tabela);
            app.UITable.Data = T; 
            app.UITable.ColumnName = nomeColunas;
                
        catch ME
            errordlg(ME.message,"ERRO","modal")               
        end


        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Menu selected function: AutoresMenu
        function AutoresMenuSelected(app, event)
            sobre
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open("RelatórioFranciscoRuivo_DanielRodrigues.pdf")
        end

        % Drop down opening function: ddProblemas
        function ddProblemasDropDownOpening(app, event)
            
        end

        % Value changed function: ddProblemas
        function ddProblemasValueChanged(app, event)
        value = app.ddProblemas.Value;
       %Sistema Mecanico Mola Massa com mov harmonico simles ou sem
            %amortecimento
            if value == '1'
                app.eF.Value='v';
                app.eG.Value='-16*u';
                app.eN.Value=100;
                app.eU0.Value=9;
                app.eV0.Value=0;
                app.eA.Value=0;
                app.eB.Value=4;
                bAtualizarButtonPushed(app, [])
            end
            %com amortecimento
            if value == '2'
                app.eF.Value='v';
                app.eG.Value='-10*v-25*u';
                app.eN.Value=100;
                app.eU0.Value=0;
                app.eV0.Value=-4;
                app.eA.Value=0;
                app.eB.Value=2;
                bAtualizarButtonPushed(app, [])
            end
            %pendulo
            if value == '3'
                app.eF.Value='v';
                app.eG.Value='-sin(u)-0.3*v';
                app.eN.Value=100;
                app.eU0.Value=pi/2;
                app.eV0.Value=0;
                app.eA.Value=0;
                app.eB.Value=15;
                bAtualizarButtonPushed(app, [])
            end
            
            %modelo vib mecanico
            if value == '4'
                app.eF.Value='v';
                app.eG.Value='4*cos(t)+2*sin(t)-2*v-2*u';
                app.eN.Value=100;
                app.eU0.Value=0;
                app.eV0.Value=3;
                app.eA.Value=0;
                app.eB.Value=15;
                bAtualizarButtonPushed(app, [])
            end
           %circuitos eletricos
            %q'(0)=8    q(0)=4
            %L=1    R=4 e(t)=15 c^-1=6  
            %q''+4q'+6q=15
            if value == '5'
                app.eF.Value='v';
                app.eG.Value='-4*v-6*u+15';
                app.eN.Value=100;
                app.eU0.Value=4;
                app.eV0.Value=8;
                app.eA.Value=0;
                app.eB.Value=15;
                bAtualizarButtonPushed(app, [])
            end


        end

        % Menu selected function: MolaMassasemAmortecimentoMenu
        function MolaMassasemAmortecimentoMenuSelected(app, event)
 
                app.eF.Value='v';
                app.eG.Value='-16*u';
                app.eN.Value=100;
                app.eU0.Value=9;
                app.eV0.Value=0;
                app.eA.Value=0;
                app.eB.Value=4;
                bAtualizarButtonPushed(app, [])
        end

        % Menu selected function: PnduloMenu
        function PnduloMenuSelected(app, event)
                app.eF.Value='v';
                app.eG.Value='-sin(u)-0.3*v';
                app.eN.Value=100;
                app.eU0.Value=pi/2;
                app.eV0.Value=0;
                app.eA.Value=0;
                app.eB.Value=15;
                bAtualizarButtonPushed(app, [])
        end

        % Menu selected function: MolaMassacomAmortecimentoMenu
        function MolaMassacomAmortecimentoMenuSelected(app, event)
                 app.eF.Value='v';
                app.eG.Value='-10*v-25*u';
                app.eN.Value=100;
                app.eU0.Value=0;
                app.eV0.Value=-4;
                app.eA.Value=0;
                app.eB.Value=2;
                bAtualizarButtonPushed(app, [])
        end

        % Button pushed function: bLimparDados
        function bLimparDadosButtonPushed(app, event)
            value = app.ddProblemas.Value;
                app.eF.Value='';
                app.eG.Value='';
                bAtualizarButtonPushed(app, [])

        end

        % Menu selected function: ModelosVibratoriosmecanicosMenu
        function ModelosVibratoriosmecanicosMenuSelected(app, event)
                app.eF.Value='v';
                app.eG.Value='4*cos(t)+2*sin(t)-2*v-2*u';
                app.eN.Value=100;
                app.eU0.Value=0;
                app.eV0.Value=3;
                app.eA.Value=0;
                app.eB.Value=15;
                bAtualizarButtonPushed(app, [])
        end

        % Menu selected function: CircuitosEletricosMenu
        function CircuitosEletricosMenuSelected(app, event)
                app.eF.Value='v';
                app.eG.Value='-4*v-6*u+15';
                app.eN.Value=100;
                app.eU0.Value=4;
                app.eV0.Value=8;
                app.eA.Value=0;
                app.eB.Value=15;
                bAtualizarButtonPushed(app, [])
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {593, 593};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {260, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.SairMenu = uimenu(app.Menu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.DocumentosMenu = uimenu(app.UIFigure);
            app.DocumentosMenu.Text = 'Documentos';
            app.AutoresMenu = uimenu(app.DocumentosMenu);
            app.AutoresMenu.MenuSelectedFcn = createCallbackFcn(app, @AutoresMenuSelected, true);
            app.AutoresMenu.Text = 'Autores';
            app.RelatorioMenu = uimenu(app.DocumentosMenu);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatorio';
            app.ProblemasPrDefenidosMenu = uimenu(app.UIFigure);
            app.ProblemasPrDefenidosMenu.Text = 'Problemas Pré Defenidos';
            app.MolaMassasemAmortecimentoMenu = uimenu(app.ProblemasPrDefenidosMenu);
            app.MolaMassasemAmortecimentoMenu.MenuSelectedFcn = createCallbackFcn(app, @MolaMassasemAmortecimentoMenuSelected, true);
            app.MolaMassasemAmortecimentoMenu.Text = 'Sistema Mecanico Mola Massa com mov harmonico simles ou sem amortecimento';
            app.MolaMassacomAmortecimentoMenu = uimenu(app.ProblemasPrDefenidosMenu);
            app.MolaMassacomAmortecimentoMenu.MenuSelectedFcn = createCallbackFcn(app, @MolaMassacomAmortecimentoMenuSelected, true);
            app.MolaMassacomAmortecimentoMenu.Text = 'Mola Massa com Amortecimento';
            app.PnduloMenu = uimenu(app.ProblemasPrDefenidosMenu);
            app.PnduloMenu.MenuSelectedFcn = createCallbackFcn(app, @PnduloMenuSelected, true);
            app.PnduloMenu.Text = 'Pêndulo';
            app.ModelosVibratoriosmecanicosMenu = uimenu(app.ProblemasPrDefenidosMenu);
            app.ModelosVibratoriosmecanicosMenu.MenuSelectedFcn = createCallbackFcn(app, @ModelosVibratoriosmecanicosMenuSelected, true);
            app.ModelosVibratoriosmecanicosMenu.Text = 'Modelos Vibratorios mecanicos';
            app.CircuitosEletricosMenu = uimenu(app.ProblemasPrDefenidosMenu);
            app.CircuitosEletricosMenu.MenuSelectedFcn = createCallbackFcn(app, @CircuitosEletricosMenuSelected, true);
            app.CircuitosEletricosMenu.Text = 'Circuitos Eletricos';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SistemasdeEquaesDiferenciaisPanel = uipanel(app.LeftPanel);
            app.SistemasdeEquaesDiferenciaisPanel.Title = 'Sistemas de Equações Diferenciais';
            app.ftuvLabel = uilabel(app.SistemasdeEquaesDiferenciaisPanel);
            app.ftuvLabel.Text = 'f(t,u,v) = ';
            app.eF = uieditfield(app.SistemasdeEquaesDiferenciaisPanel, 'text');
            app.eF.Value = 'v';
            app.aLabel = uilabel(app.SistemasdeEquaesDiferenciaisPanel);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.SistemasdeEquaesDiferenciaisPanel, 'numeric');
            app.eB = uieditfield(app.SistemasdeEquaesDiferenciaisPanel, 'numeric');
            app.eB.Value = 4;
            app.bLabel = uilabel(app.SistemasdeEquaesDiferenciaisPanel);
            app.bLabel.Text = 'b';
            app.eN = uieditfield(app.SistemasdeEquaesDiferenciaisPanel, 'numeric');
            app.eN.Value = 100;
            app.nLabel = uilabel(app.SistemasdeEquaesDiferenciaisPanel);
            app.nLabel.Text = 'n';
            app.eU0 = uieditfield(app.SistemasdeEquaesDiferenciaisPanel, 'numeric');
            app.eU0.Value = 9;
            app.u0Label = uilabel(app.SistemasdeEquaesDiferenciaisPanel);
            app.u0Label.Text = 'u0';
            app.gtuvLabel = uilabel(app.SistemasdeEquaesDiferenciaisPanel);
            app.gtuvLabel.Text = 'g(t,u,v) =';
            app.eG = uieditfield(app.SistemasdeEquaesDiferenciaisPanel, 'text');
            app.eG.Value = '-16*u';
            app.eV0 = uieditfield(app.SistemasdeEquaesDiferenciaisPanel, 'numeric');
            app.v0Label = uilabel(app.SistemasdeEquaesDiferenciaisPanel);
            app.v0Label.Text = 'v0';
            app.IntervaloLabel = uilabel(app.SistemasdeEquaesDiferenciaisPanel);
            app.IntervaloLabel.Text = 'Intervalo';
            app.CondIniciaisLabel = uilabel(app.SistemasdeEquaesDiferenciaisPanel);
            app.CondIniciaisLabel.Text = 'Cond.Iniciais';
            app.NIteraesLabel = uilabel(app.SistemasdeEquaesDiferenciaisPanel);
            app.NIteraesLabel.Text = 'Nº Iterações';
            app.ProblemasPLabel = uilabel(app.SistemasdeEquaesDiferenciaisPanel);
            app.ProblemasPLabel.Text = 'Problemas Pré Defenidos';
            app.ddProblemas = uidropdown(app.SistemasdeEquaesDiferenciaisPanel);
            app.ddProblemas.Items = {'Sistema Mecanico Mola Massa com mov harmonico simles ou sem amortecimento', 'Mola Massa com Amortecimento', 'Pendulo', 'Modelos Vibratorios mecanicos', 'Circuitos Eletricos'};
            app.ddProblemas.ItemsData = {'1', '2', '3', '4', '5'};
            app.ddProblemas.DropDownOpeningFcn = createCallbackFcn(app, @ddProblemasDropDownOpening, true);
            app.ddProblemas.ValueChangedFcn = createCallbackFcn(app, @ddProblemasValueChanged, true);
            app.ddProblemas.Value = '1';
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.bLimparDados = uibutton(app.LeftPanel, 'push');
            app.bLimparDados.ButtonPushedFcn = createCallbackFcn(app, @bLimparDadosButtonPushed, true);
            app.bLimparDados.Text = 'Limpar Dados';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Grafico')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaSED

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end